classdef App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        MorphOpen                   matlab.ui.control.Button
        MorphTest                   matlab.ui.control.Button
        LabelDropDown               matlab.ui.control.Label
        MorphStrelType              matlab.ui.control.DropDown
        LabelDropDown2              matlab.ui.control.Label
        MorphType                   matlab.ui.control.DropDown
        LabelSlider                 matlab.ui.control.Label
        MorphSlider                 matlab.ui.control.Slider
        MorphSave                   matlab.ui.control.Button
        MorphCustomName             matlab.ui.control.CheckBox
        NameLabel                   matlab.ui.control.Label
        MorphOutputName             matlab.ui.control.EditField
        MorphSliderLabel            matlab.ui.control.Label
        MarkerFileOpen              matlab.ui.control.Button
        MorphButterflyTrue          matlab.ui.control.CheckBox
        Tab2                        matlab.ui.container.Tab
        LabelSlider2                matlab.ui.control.Label
        BinarizeSlider              matlab.ui.control.Slider
        BinarizeTest                matlab.ui.control.Button
        BinarizeOpen                matlab.ui.control.Button
        BinarizeSave                matlab.ui.control.Button
        BinarizeType                matlab.ui.container.ButtonGroup
        AdaptiveButton              matlab.ui.control.RadioButton
        GlobalButton                matlab.ui.control.RadioButton
        ThreshCustomName            matlab.ui.control.CheckBox
        NameEditFieldLabel          matlab.ui.control.Label
        ThreshOutputName            matlab.ui.control.EditField
        ThreshSliderLabel           matlab.ui.control.Label
        ThreshGrayscaleTrue         matlab.ui.control.CheckBox
        EdgesTab                    matlab.ui.container.Tab
        TypeDropDownLabel           matlab.ui.control.Label
        EdgeType                    matlab.ui.control.DropDown
        EdgeOpen                    matlab.ui.control.Button
        ThresholdSliderLabel        matlab.ui.control.Label
        EdgeSlider                  matlab.ui.control.Slider
        SigmaHocrossLabel           matlab.ui.control.Label
        EdgeSigma                   matlab.ui.control.NumericEditField
        EdgeTest                    matlab.ui.control.Button
        EdgeSave                    matlab.ui.control.Button
        DirectionDropDownLabel      matlab.ui.control.Label
        EdgeDir                     matlab.ui.control.DropDown
        EdgeCustomName              matlab.ui.control.CheckBox
        EditFieldLabel              matlab.ui.control.Label
        EdgeOutputName              matlab.ui.control.EditField
        EdgeSliderLabel             matlab.ui.control.Label
        EdgeGrayscaleTrue           matlab.ui.control.CheckBox
        Tab_3                       matlab.ui.container.Tab
        IntensityOpen               matlab.ui.control.Button
        IntensityTest               matlab.ui.control.Button
        SegmentLabel                matlab.ui.control.Label
        IntensitySegment            matlab.ui.control.DropDown
        IntensitySave               matlab.ui.control.Button
        IntensityCustomName         matlab.ui.control.CheckBox
        MorphOutputName_2Label      matlab.ui.control.Label
        IntensityOutputName         matlab.ui.control.EditField
        Tab_4                       matlab.ui.container.Tab
        LabOpen                     matlab.ui.control.Button
        LabTest                     matlab.ui.control.Button
        IntensitySegment_2Label     matlab.ui.control.Label
        LabSegment                  matlab.ui.control.DropDown
        LabSave                     matlab.ui.control.Button
        LabCustomName               matlab.ui.control.CheckBox
        IntensityOutputName_2Label  matlab.ui.control.Label
        LabOutputName               matlab.ui.control.EditField
        Tab_6                       matlab.ui.container.Tab
        ManualOpen                  matlab.ui.control.Button
        ManualStart                 matlab.ui.control.Button
        LabSegment_2Label           matlab.ui.control.Label
        ManualSegment               matlab.ui.control.DropDown
        ManualShowAll               matlab.ui.control.Button
    end


    properties (Access = private)
        orig_image % Description
        strel_method % Description
        is_changable
        morph_img % Description
        orig_filename
        
        MorphInputImage
        MorphInputFilename
        MorphOutputImage
        MorphInputMarkerFilename
        
        % binary
        ThreshInputImage 
        ThreshInputFilename
        ThreshOutputImage
        
        
        % edges

        EdgeInputImage
        EdgeInputFilename
        EdgeOutputImage
        
        % intensity
        IntensityInputImage
        IntensityInputFilename
        IntensityOutputImage
        
        % lab
        LabInputImage
        LabInputFilename
        LabOutputImage
                
        % manual
        ManualInputImage
        ManualInputFilename
        ManualOutputImage
        
        Property16 % Description
    end


    methods (Access = private)

        % Button pushed function: MorphOpen
        function MorphOpenButtonPushed(app, event)
        [app.MorphInputFilename,orig_filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
        if orig_filepath~=0
            app.MorphInputImage = imread(app.MorphInputFilename)
            figure('Name','Original Image'),imshow(app.MorphInputImage)
        end
        end

        % Button pushed function: MorphTest
        function MorphTestButtonPushed(app, event)
        val = app.MorphStrelType.Value
        se = []
        slider_val = app.MorphSlider.Value
        app.MorphSliderLabel.Text = num2str(slider_val)
        
        if strcmp(val,'diamond')
           se = strel('diamond',floor(slider_val)) 
        elseif strcmp(val,'disk')
           se = strel('disk',floor(slider_val))
        
        elseif strcmp(val,'rectangle')
            
           se = strel('rectangle',[floor(slider_val) floor(slider_val)])
        end
        
        m_op_type = app.MorphType.Value
       
            
        if strcmp(m_op_type,'Erosion')
            
            app.MorphOutputImage = imerode(app.MorphInputImage,se)
        elseif strcmp(m_op_type,'Dilation')
            
            app.MorphOutputImage = imdilate (app.MorphInputImage,se)
        elseif strcmp(m_op_type,'Opening')
            
            app.MorphOutputImage = imopen(app.MorphInputImage,se)
        elseif strcmp(m_op_type,'Reconstruction')
            
                %if app.MorphButterflyTrue.Enable == 'on' 
                %    marker_image = app.MorphOutputImage
                %else
                %    marker_image = imread(app.MorphInputMarkerFilename);
                %end
                marker_image = imread(app.MorphInputMarkerFilename);
                mask_image = app.MorphInputImage
                app.MorphOutputImage = imreconstruct(marker_image,mask_image)
        end
        
        
        figure,imshow(app.MorphOutputImage)
      
        
        end

        % Value changing function: MorphSlider
        function MorphSliderValueChanging(app, event)
            
            
        end

        % Button pushed function: BinarizeOpen
        function BinarizeOpenButtonPushed(app, event)
            
            [app.ThreshInputFilename,filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            
            if filepath ~= 0
                app.ThreshInputImage = imread(app.ThreshInputFilename)
                figure('Name','InputforThresholding'),imshow(app.ThreshInputImage)
            end
            
        end

        % Value changed function: BinarizeSlider
        function BinarizeSliderValueChanged(app, event)
            
        end

        % Button pushed function: BinarizeTest
        function BinarizeTestButtonPushed(app, event)
            app.ThreshSliderLabel.Text = num2str(app.BinarizeSlider.Value)
            if app.ThreshGrayscaleTrue.Enable == 'on' 
            
            app.ThreshInputImage = app.ThreshInputImage(:,:,1)
            end
            
            if app.AdaptiveButton.Value == 1
                
                T = adaptthresh(app.ThreshInputImage)
                app.ThreshOutputImage = imbinarize(app.ThreshInputImage,T)
            elseif app.GlobalButton.Value == 1
                
                value = app.BinarizeSlider.Value;
                app.ThreshOutputImage = im2bw(app.ThreshInputImage,value)           
            
            end
           
           
            imshow(app.ThreshOutputImage)
             
        end

        % Button pushed function: BinarizeSave
        function BinarizeSaveButtonPushed(app, event)


        if app.ThreshCustomName.Value == 0
            ind = strfind(app.ThreshInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.ThreshInputFilename(1:ind),'_thresh.jpg')
            imwrite(app.ThreshOutputImage,FILENAME)
            
            
        elseif app.ThreshCustomName.Value == 1
            inputfile_format = app.ThreshInputFilename(strfind(app.ThreshInputFilename,'.'):end)
            output_filename = strcat(app.ThreshOutputName.Value,inputfile_format)
            imwrite(app.ThreshOutputImage,output_filename)
        end
        end

        % Button pushed function: MorphSave
        function MorphSaveButtonPushed(app, event)
            
        %-----------------------------------------------------------------
        
        if app.MorphCustomName.Value == 0
            ind = strfind(app.MorphInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.MorphInputFilename(1:ind),'_morph.jpg')
            imwrite(app.MorphOutputImage,FILENAME)
            
            
        elseif app.MorphCustomName.Value == 1
            inputfile_format = app.MorphInputFilename(strfind(app.MorphInputFilename,'.'):end)
            output_filename = strcat(app.MorphOutputName.Value,inputfile_format)
            imwrite(app.MorphOutputImage,output_filename)
            
        end
        end

        % Button pushed function: EdgeOpen
        function EdgeOpenButtonPushed(app, event)
            
            [app.EdgeInputFilename,filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if filepath~=0
                app.EdgeInputImage = imread(app.EdgeInputFilename)
                figure('Name','Input for Edge detection'),imshow(app.EdgeInputImage)
            end
        end

        % Button pushed function: EdgeTest
        function EdgeTestButtonPushed(app, event)
            
            t = app.EdgeSlider.Value
            app.EdgeSliderLabel.Text = num2str(t)
            dir = app.EdgeDir.Value
            sigma = app.EdgeSigma.Value
            img = app.EdgeInputImage
            edge_type =app.EdgeType.Value
            
            if app.ThreshGrayscaleTrue.Enable == 'on'
                img = img(:,:,1);
            end
            
            if strcmp(edge_type,'Sobel')
                app.EdgeOutputImage = edge(img,'sobel',t,dir)
            elseif strcmp(edge_type,'Prewitt')
                app.EdgeOutputImage = edge(img,'prewitt',t,dir)
            elseif strcmp(edge_type,'LOG')
                app.EdgeOutputImage = edge(img,'log',t,sigma)
            elseif strcmp(edge_type,'0-cross')
                app.EdgeOutputImage = edge(img,'zerocross',t,sigma)
            elseif strcmp(edge_type,'Canny')
                app.EdgeOutputImage = edge(img,'canny',t,sigma)
            end
            
            imshow(app.EdgeOutputImage)
        end

        % Button pushed function: EdgeSave
        function EdgeSaveButtonPushed(app, event)
         
        if app.EdgeCustomName.Value == 0
            ind = strfind(app.EdgeInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.EdgeInputFilename(1:ind),'_edge.jpg')
            imwrite(app.EdgeOutputImage,FILENAME)
            
            
        elseif app.EdgeCustomName.Value == 1
            inputfile_format = app.EdgeInputFilename(strfind(app.EdgeInputFilename,'.'):end)
            output_filename = strcat(app.EdgeOutputName.Value,inputfile_format)
            imwrite(app.EdgeOutputImage,output_filename)
            
        end
            
        
        end

        % Value changed function: MorphType
        function MorphTypeValueChanged(app, event)
            value = app.MorphType.Value;
            if value == 'Reconstruction'
                app.MarkerFileOpen.Enable= 'on';
                
            end
        end

        % Button pushed function: MarkerFileOpen
        function MarkerFileOpenButtonPushed(app, event)
            [app.MorphInputMarkerFilename, filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if filepath ~= 0 
                figure('Name','Original Image'),imshow(app.MorphInputMarkerFilename)
            end
            
        end

        % Button pushed function: IntensityOpen
        function IntensityOpenButtonPushed(app, event)
            [app.IntensityInputFilename,orig_filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if orig_filepath~=0
                app.IntensityInputImage = imread(app.IntensityInputFilename)
                figure('Name','Original Image'),imshow(app.IntensityInputImage)
            end
        end

        % Button pushed function: IntensityTest
        function IntensityTestButtonPushed(app, event)
            I =  app.IntensityInputImage
            G2 = rgb2gray(I);
            G = medfilt2(G2);
            n = str2double(app.IntensitySegment.Value)
            L = get_range(n,1,255);
            O = zeros([size(I) n]);
            
            figure;
            for i=(1:n)
                lower = L(i,1);
                upper = L(i,2);
                Seg = O(:,:,i);
                ind = find(G >=lower & G <upper)
                Seg(ind) = 1; 
                subplot(1,n,i),imshow(medfilt2(Seg));
            end
            
        end

        % Button pushed function: IntensitySave
        function IntensitySaveButtonPushed(app, event)
        if app.IntensityCustomName.Value == 0
            ind = strfind(app.IntensityInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.IntensityInputFilename(1:ind),'_Intensity.jpg')
            imwrite(app.IntensityOutputImage,FILENAME)
            
            
        elseif app.IntensityCustomName.Value == 1
            inputfile_format = app.IntensityInputFilename(strfind(app.IntensityInputFilename,'.'):end)
            output_filename = strcat(app.IntensityOutputName.Value,inputfile_format)
            imwrite(app.IntensityOutputImage,output_filename)
            
        end
        end

        % Value changed function: IntensitySegment
        function IntensitySegmentValueChanged(app, event)
            
        end

        % Button pushed function: LabOpen
        function LabOpenButtonPushed(app, event)
            [app.LabInputFilename,orig_filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if orig_filepath~=0
                app.LabInputImage = imread(app.LabInputFilename)
                figure('Name','Original Image'),imshow(app.LabInputImage)
            end
        end

        % Button pushed function: LabTest
        function LabTestButtonPushed(app, event)
            I = app.LabInputImage;
            n = str2double(app.LabSegment.Value)
            L = lab_segmentation(n,I);
            
            figure;
            for i = 1:n
                subplot(2,double(int32(n/2)),i),imshow(L(:,:,:,i))
                % imwrite(L(:,:,:,i), strcat('cig2_morph_lab',num2str(i),'.jpg'),'jpg')
            end
        end

        % Button pushed function: LabSave
        function LabSaveButtonPushed(app, event)
        if app.LabCustomName.Value == 0
            ind = strfind(app.LabInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.LabInputFilename(1:ind),'_Lab.jpg')
            imwrite(app.LabOutputImage,FILENAME)
            
            
        elseif app.LabCustomName.Value == 1
            inputfile_format = app.LabInputFilename(strfind(app.LabInputFilename,'.'):end)
            output_filename = strcat(app.LabOutputName.Value,inputfile_format)
            imwrite(app.LabOutputImage,output_filename)
            
        end
        end

        % Button pushed function: ManualOpen
        function ManualOpenButtonPushed(app, event)
            [app.ManualInputFilename,orig_filepath] = get(handles.text4, 'String')
            if orig_filepath~=0
                app.ManualInputImage = imread(app.ManualInputFilename)
                figure('Name','Original Image'),imshow(app.ManualInputImage)
            end
        end

        % Button pushed function: ManualStart
        function ManualStartButtonPushed(app, event)
            n = str2double(app.ManualSegment.Value)
            for i = 1:n
                OUTPUT_FILENAME = strcat('manual_segment_',num2str(i),'.JPG');
                
                I = app.ManualInputImage;
                [BW ,xi,yi] = roipoly(I);
                
                I2 = I;
                BW_i = find(BW~=1);
                BW_i = BW_i.';
                
                R = I2(:,:,1);
                G = I2(:,:,2);
                B = I2(:,:,3);
                
                R(BW_i)=0;
                G(BW_i)=0;
                B(BW_i)=0;
                
                I2(:,:,1) = R;
                I2(:,:,2) = G;
                I2(:,:,3) = B;
                
                imwrite(I2,OUTPUT_FILENAME);
            end
            close all
        end

        % Button pushed function: ManualShowAll
        function ManualShowAllButtonPushed(app, event)
            figure;
            n = str2double(app.ManualSegment.Value);
            for i = 1:n
                L = imread(strcat('manual_segment_',num2str(i),'.JPG'));
                subplot(2,double(int32(n/2)),i);
                imshow(L);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Morphology';
            app.Tab.Units = 'pixels';
            app.MorphOpen = uibutton(app.Tab, 'push');
            app.MorphOpen.ButtonPushedFcn = createCallbackFcn(app, @MorphOpenButtonPushed, true);
            app.MorphOpen.Icon = 'circle.jpg';
            app.MorphOpen.Text = 'Open Image';
            app.MorphTest = uibutton(app.Tab, 'push');
            app.MorphTest.ButtonPushedFcn = createCallbackFcn(app, @MorphTestButtonPushed, true);
            app.MorphTest.Text = 'Test';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'SE Type';
            app.MorphStrelType = uidropdown(app.Tab);
            app.MorphStrelType.Items = {'diamond', 'disk', 'line', 'octagon', 'rectangle', 'square', 'cube', 'cuboid', 'sphere'};
            app.MorphStrelType.Value = 'diamond';
            app.LabelDropDown2 = uilabel(app.Tab);
            app.LabelDropDown2.Text = 'Type';
            app.MorphType = uidropdown(app.Tab);
            app.MorphType.Items = {'Erosion', 'Dilation', 'Opening', 'Reconstruction'};
            app.MorphType.ValueChangedFcn = createCallbackFcn(app, @MorphTypeValueChanged, true);
            app.MorphType.Value = 'Erosion';
            app.LabelSlider = uilabel(app.Tab);
            app.LabelSlider.Text = '';
            app.MorphSlider = uislider(app.Tab);
            app.MorphSlider.ValueChangingFcn = createCallbackFcn(app, @MorphSliderValueChanging, true);
            app.MorphSave = uibutton(app.Tab, 'push');
            app.MorphSave.ButtonPushedFcn = createCallbackFcn(app, @MorphSaveButtonPushed, true);
            app.MorphSave.Text = 'Save';
            app.MorphCustomName = uicheckbox(app.Tab);
            app.MorphCustomName.Text = 'Custom Name';
            app.NameLabel = uilabel(app.Tab);
            app.NameLabel.Text = 'Name';
            app.MorphOutputName = uieditfield(app.Tab, 'text');
            app.MorphSliderLabel = uilabel(app.Tab);
            app.MorphSliderLabel.Text = '0';
            app.MarkerFileOpen = uibutton(app.Tab, 'push');
            app.MarkerFileOpen.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileOpenButtonPushed, true);
            app.MarkerFileOpen.Enable = 'off';
            app.MarkerFileOpen.Text = 'Marker File';
            app.MorphButterflyTrue = uicheckbox(app.Tab);
            app.MorphButterflyTrue.Text = 'Butterfly';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Binarize';
            app.Tab2.Units = 'pixels';
            app.LabelSlider2 = uilabel(app.Tab2);
            app.LabelSlider2.Text = 'Threshold';
            app.BinarizeSlider = uislider(app.Tab2);
            app.BinarizeSlider.Limits = [0 1];
            app.BinarizeSlider.ValueChangedFcn = createCallbackFcn(app, @BinarizeSliderValueChanged, true);
            app.BinarizeTest = uibutton(app.Tab2, 'push');
            app.BinarizeTest.ButtonPushedFcn = createCallbackFcn(app, @BinarizeTestButtonPushed, true);
            app.BinarizeTest.Text = 'Test';
            app.BinarizeOpen = uibutton(app.Tab2, 'push');
            app.BinarizeOpen.ButtonPushedFcn = createCallbackFcn(app, @BinarizeOpenButtonPushed, true);
            app.BinarizeOpen.Icon = 'circle.jpg';
            app.BinarizeOpen.Text = 'Open Image';
            app.BinarizeSave = uibutton(app.Tab2, 'push');
            app.BinarizeSave.ButtonPushedFcn = createCallbackFcn(app, @BinarizeSaveButtonPushed, true);
            app.BinarizeSave.Text = 'Save';
            app.BinarizeType = uibuttongroup(app.Tab2);
            app.BinarizeType.Title = 'Button Group';
            app.AdaptiveButton = uiradiobutton(app.BinarizeType);
            app.AdaptiveButton.Text = 'Adaptive';
            app.AdaptiveButton.Value = true;
            app.GlobalButton = uiradiobutton(app.BinarizeType);
            app.GlobalButton.Text = 'Global';
            app.ThreshCustomName = uicheckbox(app.Tab2);
            app.ThreshCustomName.Text = 'Custom Name';
            app.NameEditFieldLabel = uilabel(app.Tab2);
            app.NameEditFieldLabel.Text = 'Name';
            app.ThreshOutputName = uieditfield(app.Tab2, 'text');
            app.ThreshSliderLabel = uilabel(app.Tab2);
            app.ThreshSliderLabel.Text = '0';
            app.ThreshGrayscaleTrue = uicheckbox(app.Tab2);
            app.ThreshGrayscaleTrue.Text = '1D Grayscale First';
            app.EdgesTab = uitab(app.TabGroup);
            app.EdgesTab.Title = 'Edges';
            app.EdgesTab.Units = 'pixels';
            app.TypeDropDownLabel = uilabel(app.EdgesTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.EdgeType = uidropdown(app.EdgesTab);
            app.EdgeType.Items = {'Sobel', 'Prewitt', 'LOG', '0-cross', 'Canny'};
            app.EdgeType.Value = 'Sobel';
            app.EdgeOpen = uibutton(app.EdgesTab, 'push');
            app.EdgeOpen.ButtonPushedFcn = createCallbackFcn(app, @EdgeOpenButtonPushed, true);
            app.EdgeOpen.Icon = 'circle.jpg';
            app.EdgeOpen.Text = 'Open Image';
            app.ThresholdSliderLabel = uilabel(app.EdgesTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.EdgeSlider = uislider(app.EdgesTab);
            app.EdgeSlider.Limits = [0 1];
            app.EdgeSlider.Value = 1;
            app.SigmaHocrossLabel = uilabel(app.EdgesTab);
            app.SigmaHocrossLabel.Text = 'Sigma/H(o-cross)';
            app.EdgeSigma = uieditfield(app.EdgesTab, 'numeric');
            app.EdgeSigma.Value = 1;
            app.EdgeTest = uibutton(app.EdgesTab, 'push');
            app.EdgeTest.ButtonPushedFcn = createCallbackFcn(app, @EdgeTestButtonPushed, true);
            app.EdgeTest.Text = 'Test';
            app.EdgeSave = uibutton(app.EdgesTab, 'push');
            app.EdgeSave.ButtonPushedFcn = createCallbackFcn(app, @EdgeSaveButtonPushed, true);
            app.EdgeSave.Text = 'Save';
            app.DirectionDropDownLabel = uilabel(app.EdgesTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.EdgeDir = uidropdown(app.EdgesTab);
            app.EdgeDir.Items = {'Horizontal', 'Vertical', 'Option 3', 'Option 4'};
            app.EdgeDir.Value = 'Horizontal';
            app.EdgeCustomName = uicheckbox(app.EdgesTab);
            app.EdgeCustomName.Text = 'Custom Name';
            app.EditFieldLabel = uilabel(app.EdgesTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EdgeOutputName = uieditfield(app.EdgesTab, 'text');
            app.EdgeSliderLabel = uilabel(app.EdgesTab);
            app.EdgeSliderLabel.Text = '0';
            app.EdgeGrayscaleTrue = uicheckbox(app.EdgesTab);
            app.EdgeGrayscaleTrue.Text = '1D Grayscale First';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Intensity';
            app.Tab_3.Units = 'pixels';
            app.IntensityOpen = uibutton(app.Tab_3, 'push');
            app.IntensityOpen.ButtonPushedFcn = createCallbackFcn(app, @IntensityOpenButtonPushed, true);
            app.IntensityOpen.Icon = 'circle.jpg';
            app.IntensityOpen.Text = 'Open Image';
            app.IntensityTest = uibutton(app.Tab_3, 'push');
            app.IntensityTest.ButtonPushedFcn = createCallbackFcn(app, @IntensityTestButtonPushed, true);
            app.IntensityTest.Text = 'Test';
            app.SegmentLabel = uilabel(app.Tab_3);
            app.SegmentLabel.Text = 'Segment';
            app.IntensitySegment = uidropdown(app.Tab_3);
            app.IntensitySegment.Items = {'1', '2', '3', '4', '5', '6'};
            app.IntensitySegment.ValueChangedFcn = createCallbackFcn(app, @IntensitySegmentValueChanged, true);
            app.IntensitySegment.Value = '1';
            app.IntensitySave = uibutton(app.Tab_3, 'push');
            app.IntensitySave.ButtonPushedFcn = createCallbackFcn(app, @IntensitySaveButtonPushed, true);
            app.IntensitySave.Text = 'Save';
            app.IntensityCustomName = uicheckbox(app.Tab_3);
            app.IntensityCustomName.Text = 'Custom Name';
            app.MorphOutputName_2Label = uilabel(app.Tab_3);
            app.MorphOutputName_2Label.Text = 'Name';
            app.IntensityOutputName = uieditfield(app.Tab_3, 'text');
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Lab';
            app.Tab_4.Units = 'pixels';
            app.LabOpen = uibutton(app.Tab_4, 'push');
            app.LabOpen.ButtonPushedFcn = createCallbackFcn(app, @LabOpenButtonPushed, true);
            app.LabOpen.Icon = 'circle.jpg';
            app.LabOpen.Text = 'Open Image';
            app.LabTest = uibutton(app.Tab_4, 'push');
            app.LabTest.ButtonPushedFcn = createCallbackFcn(app, @LabTestButtonPushed, true);
            app.LabTest.Text = 'Test';
            app.IntensitySegment_2Label = uilabel(app.Tab_4);
            app.IntensitySegment_2Label.Text = 'Segment';
            app.LabSegment = uidropdown(app.Tab_4);
            app.LabSegment.Items = {'1', '2', '3', '4', '5', '6'};
            app.LabSegment.Value = '1';
            app.LabSave = uibutton(app.Tab_4, 'push');
            app.LabSave.ButtonPushedFcn = createCallbackFcn(app, @LabSaveButtonPushed, true);
            app.LabSave.Text = 'Save';
            app.LabCustomName = uicheckbox(app.Tab_4);
            app.LabCustomName.Text = 'Custom Name';
            app.IntensityOutputName_2Label = uilabel(app.Tab_4);
            app.IntensityOutputName_2Label.Text = 'Name';
            app.LabOutputName = uieditfield(app.Tab_4, 'text');
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = 'Manual';
            app.Tab_6.Units = 'pixels';
            app.ManualOpen = uibutton(app.Tab_6, 'push');
            app.ManualOpen.ButtonPushedFcn = createCallbackFcn(app, @ManualOpenButtonPushed, true);
            app.ManualOpen.Icon = 'circle.jpg';
            app.ManualOpen.Text = 'Open Image';
            app.ManualStart = uibutton(app.Tab_6, 'push');
            app.ManualStart.ButtonPushedFcn = createCallbackFcn(app, @ManualStartButtonPushed, true);
            app.ManualStart.Text = 'Start';
            app.LabSegment_2Label = uilabel(app.Tab_6);
            app.LabSegment_2Label.Text = 'Segment';
            app.ManualSegment = uidropdown(app.Tab_6);
            app.ManualSegment.Items = {'1', '2', '3', '4', '5', '6'};
            app.ManualSegment.Value = '1';
            app.ManualShowAll = uibutton(app.Tab_6, 'push');
            app.ManualShowAll.ButtonPushedFcn = createCallbackFcn(app, @ManualShowAllButtonPushed, true);
            app.ManualShowAll.Text = 'Show All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end