classdef preprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        LabelDropDown           matlab.ui.control.Label
        MorphStrelType          matlab.ui.control.DropDown
        LabelDropDown2          matlab.ui.control.Label
        MorphType               matlab.ui.control.DropDown
        LabelSlider             matlab.ui.control.Label
        MorphSlider             matlab.ui.control.Slider
        MorphSliderLabel        matlab.ui.control.Label
        MarkerFileOpen          matlab.ui.control.Button
        MorphButterflyTrue      matlab.ui.control.CheckBox
        Tab2                    matlab.ui.container.Tab
        LabelSlider2            matlab.ui.control.Label
        BinarizeSlider          matlab.ui.control.Slider
        BinarizeType            matlab.ui.container.ButtonGroup
        AdaptiveButton          matlab.ui.control.RadioButton
        GlobalButton            matlab.ui.control.RadioButton
        ThreshSliderLabel       matlab.ui.control.Label
        ThreshGrayscaleTrue     matlab.ui.control.CheckBox
        EdgesTab                matlab.ui.container.Tab
        TypeDropDownLabel       matlab.ui.control.Label
        EdgeType                matlab.ui.control.DropDown
        ThresholdSliderLabel    matlab.ui.control.Label
        EdgeSlider              matlab.ui.control.Slider
        SigmaHocrossLabel       matlab.ui.control.Label
        EdgeSigma               matlab.ui.control.NumericEditField
        DirectionDropDownLabel  matlab.ui.control.Label
        EdgeDir                 matlab.ui.control.DropDown
        EdgeSliderLabel         matlab.ui.control.Label
        EdgeGrayscaleTrue       matlab.ui.control.CheckBox
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        orig_image % Description
        strel_method % Description
        is_changable
        morph_img % Description
        orig_filename
        
        MorphInputImage
        MorphInputFilename
        MorphOutputImage
        MorphInputMarkerFilename
        
        % binary
        ThreshInputImage 
        ThreshInputFilename
        ThreshOutputImage
        
        
        % edges

        EdgeInputImage
        EdgeInputFilename
        EdgeOutputImage
        
        % intensity
        IntensityInputImage
        IntensityInputFilename
        IntensityOutputImage
        
        % lab
        LabInputImage
        LabInputFilename
        LabOutputImage
                
        % manual
        ManualInputImage
        ManualInputFilename
        ManualOutputImage
        
        Property16 % Description
    end


    methods (Access = private)

        % Callback function
        function MorphOpenButtonPushed(app, event)
        [app.MorphInputFilename,orig_filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
        if orig_filepath~=0
            app.MorphInputImage = imread(app.MorphInputFilename)
            figure('Name','Original Image'),imshow(app.MorphInputImage)
        end
        end

        % Callback function
        function MorphTestButtonPushed(app, event)
        app.MorphInputImage = imread('image.jpg');
        val = app.MorphStrelType.Value;
        se = [];
        slider_val = app.MorphSlider.Value;
        app.MorphSliderLabel.Text = num2str(slider_val);
        
        if strcmp(val,'diamond')
           se = strel('diamond',floor(slider_val)); 
        elseif strcmp(val,'disk')
           se = strel('disk',floor(slider_val));
        
        elseif strcmp(val,'rectangle')
            
           se = strel('rectangle',[floor(slider_val) floor(slider_val)]);
        end
        
        m_op_type = app.MorphType.Value;
       
            
        if strcmp(m_op_type,'Erosion')
            
            app.MorphOutputImage = imerode(app.MorphInputImage,se);
        elseif strcmp(m_op_type,'Dilation')
            
            app.MorphOutputImage = imdilate (app.MorphInputImage,se);
        elseif strcmp(m_op_type,'Opening')
            
            app.MorphOutputImage = imopen(app.MorphInputImage,se);
        elseif strcmp(m_op_type,'Reconstruction')
            
                %if app.MorphButterflyTrue.Enable == 'on' 
                %    marker_image = app.MorphOutputImage
                %else
                %    marker_image = imread(app.MorphInputMarkerFilename);
                %end
                marker_image = imread(app.MorphInputMarkerFilename);
                mask_image = app.MorphInputImage;
                app.MorphOutputImage = imreconstruct(marker_image,mask_image);
        end
        
        
        output_filename = strcat('image_preprocessed.jpg');
        imwrite(app.MorphOutputImage,output_filename);
            
        end

        % Value changing function: MorphSlider
        function MorphSliderValueChanging(app, event)
                    app.MorphInputImage = imread('image.jpg');
        val = app.MorphStrelType.Value;
        se = [];
        slider_val = app.MorphSlider.Value;
        app.MorphSliderLabel.Text = num2str(slider_val);
        
        if strcmp(val,'diamond')
           se = strel('diamond',floor(slider_val)); 
        elseif strcmp(val,'disk')
           se = strel('disk',floor(slider_val));
        
        elseif strcmp(val,'rectangle')
            
           se = strel('rectangle',[floor(slider_val) floor(slider_val)]);
        end
        
        m_op_type = app.MorphType.Value;
       
            
        if strcmp(m_op_type,'Erosion')
            
            app.MorphOutputImage = imerode(app.MorphInputImage,se);
        elseif strcmp(m_op_type,'Dilation')
            
            app.MorphOutputImage = imdilate (app.MorphInputImage,se);
        elseif strcmp(m_op_type,'Opening')
            
            app.MorphOutputImage = imopen(app.MorphInputImage,se);
        elseif strcmp(m_op_type,'Reconstruction')
            
                %if app.MorphButterflyTrue.Enable == 'on' 
                %    marker_image = app.MorphOutputImage
                %else
                %    marker_image = imread(app.MorphInputMarkerFilename);
                %end
                marker_image = imread(app.MorphInputMarkerFilename);
                mask_image = app.MorphInputImage;
                app.MorphOutputImage = imreconstruct(marker_image,mask_image);
        end
        
        
        output_filename = strcat('image_preprocessed.jpg');
        imwrite(app.MorphOutputImage,output_filename);
            
        end

        % Callback function
        function BinarizeOpenButtonPushed(app, event)
            
            [app.ThreshInputFilename,filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            
            if filepath ~= 0
                app.ThreshInputImage = imread(app.ThreshInputFilename)
                figure('Name','InputforThresholding'),imshow(app.ThreshInputImage)
            end
            
        end

        % Value changed function: BinarizeSlider
        function BinarizeSliderValueChanged(app, event)
            app.ThreshInputImage = imread('image.jpg');
            app.ThreshSliderLabel.Text = num2str(app.BinarizeSlider.Value);
            if app.ThreshGrayscaleTrue.Enable == 'on' 
            
            app.ThreshInputImage = app.ThreshInputImage(:,:,1);
            end
            
            if app.AdaptiveButton.Value == 1
                
                T = adaptthresh(app.ThreshInputImage);
                app.ThreshOutputImage = imbinarize(app.ThreshInputImage,T);
            elseif app.GlobalButton.Value == 1
                
                value = app.BinarizeSlider.Value;
                app.ThreshOutputImage = im2bw(app.ThreshInputImage,value) ;          
            
            end
           
            output_filename = strcat('image_preprocessed.jpg');
            imwrite(app.ThreshOutputImage,output_filename);
        end

        % Callback function
        function BinarizeTestButtonPushed(app, event)
            app.ThreshInputImage = imread('image.jpg');
            app.ThreshSliderLabel.Text = num2str(app.BinarizeSlider.Value);
            if app.ThreshGrayscaleTrue.Enable == 'on' 
            
            app.ThreshInputImage = app.ThreshInputImage(:,:,1);
            end
            
            if app.AdaptiveButton.Value == 1
                
                T = adaptthresh(app.ThreshInputImage);
                app.ThreshOutputImage = imbinarize(app.ThreshInputImage,T);
            elseif app.GlobalButton.Value == 1
                
                value = app.BinarizeSlider.Value;
                app.ThreshOutputImage = im2bw(app.ThreshInputImage,value) ;          
            
            end
           
            output_filename = strcat('image_preprocessed.jpg');
            imwrite(app.ThreshOutputImage,output_filename);
             
        end

        % Callback function
        function BinarizeSaveButtonPushed(app, event)


        if app.ThreshCustomName.Value == 0
            ind = strfind(app.ThreshInputFilename,'.');
            ind = ind(end) - 1;
            FILENAME = strcat(app.ThreshInputFilename(1:ind),'_thresh.jpg');
            imwrite(app.ThreshOutputImage,FILENAME);
            
            
        elseif app.ThreshCustomName.Value == 1
            inputfile_format = app.ThreshInputFilename(strfind(app.ThreshInputFilename,'.'):end);
            output_filename = strcat(app.ThreshOutputName.Value,inputfile_format);
            imwrite(app.ThreshOutputImage,output_filename);
        end
        end

        % Callback function
        function MorphSaveButtonPushed(app, event)
            
        %-----------------------------------------------------------------
        
        if app.MorphCustomName.Value == 0
            ind = strfind(app.MorphInputFilename,'.');
            ind = ind(end) - 1;
            FILENAME = strcat(app.MorphInputFilename(1:ind),'_morph.jpg');
            imwrite(app.MorphOutputImage,FILENAME);
            
            
        elseif app.MorphCustomName.Value == 1
            inputfile_format = app.MorphInputFilename(strfind(app.MorphInputFilename,'.'):end);
            output_filename = strcat(app.MorphOutputName.Value,inputfile_format);
            imwrite(app.MorphOutputImage,output_filename);
            
        end
        end

        % Callback function
        function EdgeOpenButtonPushed(app, event)
            
            [app.EdgeInputFilename,filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if filepath~=0
                app.EdgeInputImage = imread(app.EdgeInputFilename)
                figure('Name','Input for Edge detection'),imshow(app.EdgeInputImage)
            end
        end

        % Callback function
        function EdgeTestButtonPushed(app, event)
            app.EdgeInputImage = imread('image.jpg');
            t = app.EdgeSlider.Value;
            app.EdgeSliderLabel.Text = num2str(t);
            dir = app.EdgeDir.Value;
            sigma = app.EdgeSigma.Value;
            img = app.EdgeInputImage;
            edge_type =app.EdgeType.Value;
            
            if app.ThreshGrayscaleTrue.Enable == 'on'
                img = img(:,:,1);
            end
            
            if strcmp(edge_type,'Sobel')
                app.EdgeOutputImage = edge(img,'sobel',t,dir);
            elseif strcmp(edge_type,'Prewitt')
                app.EdgeOutputImage = edge(img,'prewitt',t,dir);
            elseif strcmp(edge_type,'LOG')
                app.EdgeOutputImage = edge(img,'log',t,sigma);
            elseif strcmp(edge_type,'0-cross')
                app.EdgeOutputImage = edge(img,'zerocross',t,sigma);
            elseif strcmp(edge_type,'Canny')
                app.EdgeOutputImage = edge(img,'canny',t,sigma);
            end
            
            output_filename = strcat('image_preprocessed.jpg')
            imwrite(app.EdgeOutputImage,output_filename)
            
        
        end

        % Callback function
        function EdgeSaveButtonPushed(app, event)
         
        if app.EdgeCustomName.Value == 0
            ind = strfind(app.EdgeInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.EdgeInputFilename(1:ind),'_edge.jpg')
            imwrite(app.EdgeOutputImage,FILENAME)
            
            
        elseif app.EdgeCustomName.Value == 1
            inputfile_format = app.EdgeInputFilename(strfind(app.EdgeInputFilename,'.'):end)
            output_filename = strcat(app.EdgeOutputName.Value,inputfile_format)
            imwrite(app.EdgeOutputImage,output_filename)
            
        end
            
        
        end

        % Value changed function: MorphType
        function MorphTypeValueChanged(app, event)
            value = app.MorphType.Value;
            if value == 'Reconstruction'
                app.MarkerFileOpen.Enable= 'on';
                
            end
        end

        % Button pushed function: MarkerFileOpen
        function MarkerFileOpenButtonPushed(app, event)
            [app.MorphInputMarkerFilename, filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if filepath ~= 0 
                figure('Name','Original Image'),imshow(app.MorphInputMarkerFilename)
            end
            
        end

        % Callback function
        function IntensityOpenButtonPushed(app, event)
            [app.IntensityInputFilename,orig_filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if orig_filepath~=0
                app.IntensityInputImage = imread(app.IntensityInputFilename)
                figure('Name','Original Image'),imshow(app.IntensityInputImage)
            end
        end

        % Callback function
        function IntensityTestButtonPushed(app, event)
            I =  app.IntensityInputImage
            G2 = rgb2gray(I);
            G = medfilt2(G2);
            n = str2double(app.IntensitySegment.Value)
            L = get_range(n,1,255);
            O = zeros([size(I) n]);
            
            figure;
            for i=(1:n)
                lower = L(i,1);
                upper = L(i,2);
                Seg = O(:,:,i);
                ind = find(G >=lower & G <upper)
                Seg(ind) = 1; 
                subplot(1,n,i),imshow(medfilt2(Seg));
            end
            
        end

        % Callback function
        function IntensitySaveButtonPushed(app, event)
        if app.IntensityCustomName.Value == 0
            ind = strfind(app.IntensityInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.IntensityInputFilename(1:ind),'_Intensity.jpg')
            imwrite(app.IntensityOutputImage,FILENAME)
            
            
        elseif app.IntensityCustomName.Value == 1
            inputfile_format = app.IntensityInputFilename(strfind(app.IntensityInputFilename,'.'):end)
            output_filename = strcat(app.IntensityOutputName.Value,inputfile_format)
            imwrite(app.IntensityOutputImage,output_filename)
            
        end
        end

        % Callback function
        function LabOpenButtonPushed(app, event)
            [app.LabInputFilename,orig_filepath] = uigetfile({'*.bmp;*.jpg;*.tif;*.png;*.gif','All Image Files'})
            if orig_filepath~=0
                app.LabInputImage = imread(app.LabInputFilename)
                figure('Name','Original Image'),imshow(app.LabInputImage)
            end
        end

        % Callback function
        function LabTestButtonPushed(app, event)
            I = app.LabInputImage;
            n = str2double(app.LabSegment.Value)
            L = lab_segmentation(n,I);
            
            figure;
            for i = 1:n
                subplot(2,double(int32(n/2)),i),imshow(L(:,:,:,i))
                % imwrite(L(:,:,:,i), strcat('cig2_morph_lab',num2str(i),'.jpg'),'jpg')
            end
        end

        % Callback function
        function LabSaveButtonPushed(app, event)
        if app.LabCustomName.Value == 0
            ind = strfind(app.LabInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.LabInputFilename(1:ind),'_Lab.jpg')
            imwrite(app.LabOutputImage,FILENAME)
            
            
        elseif app.LabCustomName.Value == 1
            inputfile_format = app.LabInputFilename(strfind(app.LabInputFilename,'.'):end)
            output_filename = strcat(app.LabOutputName.Value,inputfile_format)
            imwrite(app.LabOutputImage,output_filename)
            
        end
        end

        % Callback function
        function ManualOpenButtonPushed(app, event)
            [app.ManualInputFilename,orig_filepath] = get(handles.text4, 'String')
            if orig_filepath~=0
                app.ManualInputImage = imread(app.ManualInputFilename)
                figure('Name','Original Image'),imshow(app.ManualInputImage)
            end
        end

        % Callback function
        function ManualStartButtonPushed(app, event)
            n = str2double(app.ManualSegment.Value)
            for i = 1:n
                OUTPUT_FILENAME = strcat('manual_segment_',num2str(i),'.JPG');
                
                I = app.ManualInputImage;
                [BW ,xi,yi] = roipoly(I);
                
                I2 = I;
                BW_i = find(BW~=1);
                BW_i = BW_i.';
                
                R = I2(:,:,1);
                G = I2(:,:,2);
                B = I2(:,:,3);
                
                R(BW_i)=0;
                G(BW_i)=0;
                B(BW_i)=0;
                
                I2(:,:,1) = R;
                I2(:,:,2) = G;
                I2(:,:,3) = B;
                
                imwrite(I2,OUTPUT_FILENAME);
            end
            close all
        end

        % Callback function
        function ManualShowAllButtonPushed(app, event)
            figure;
            n = str2double(app.ManualSegment.Value);
            for i = 1:n
                L = imread(strcat('manual_segment_',num2str(i),'.JPG'));
                subplot(2,double(int32(n/2)),i);
                imshow(L);
            end
            
        end

        % Value changed function: EdgeSlider
        function EdgeSliderValueChanged(app, event)
            app.EdgeInputImage = imread('image.jpg');
            t = app.EdgeSlider.Value;
            app.EdgeSliderLabel.Text = num2str(t);
            dir = app.EdgeDir.Value;
            sigma = app.EdgeSigma.Value;
            img = app.EdgeInputImage;
            edge_type =app.EdgeType.Value;
            
            if app.ThreshGrayscaleTrue.Enable == 'on'
                img = img(:,:,1);
            end
            
            if strcmp(edge_type,'Sobel')
                app.EdgeOutputImage = edge(img,'sobel',t/10,dir);
            elseif strcmp(edge_type,'Prewitt')
                app.EdgeOutputImage = edge(img,'prewitt',t/10,dir);
            elseif strcmp(edge_type,'LOG')
                app.EdgeOutputImage = edge(img,'log',t/10,sigma);
            elseif strcmp(edge_type,'0-cross')
                app.EdgeOutputImage = edge(img,'zerocross',t/10,sigma);
            elseif strcmp(edge_type,'Canny')
                app.EdgeOutputImage = edge(img,'canny',t/10,sigma);
            end
            
            output_filename = strcat('image_preprocessed.jpg')
            imwrite(app.EdgeOutputImage,output_filename)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Morphology';
            app.Tab.Units = 'pixels';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'SE Type';
            app.MorphStrelType = uidropdown(app.Tab);
            app.MorphStrelType.Items = {'diamond', 'disk', 'line', 'octagon', 'rectangle', 'square', 'cube', 'cuboid', 'sphere'};
            app.MorphStrelType.Value = 'diamond';
            app.LabelDropDown2 = uilabel(app.Tab);
            app.LabelDropDown2.Text = 'Type';
            app.MorphType = uidropdown(app.Tab);
            app.MorphType.Items = {'Erosion', 'Dilation', 'Opening', 'Reconstruction'};
            app.MorphType.ValueChangedFcn = createCallbackFcn(app, @MorphTypeValueChanged, true);
            app.MorphType.Value = 'Erosion';
            app.LabelSlider = uilabel(app.Tab);
            app.LabelSlider.Text = '';
            app.MorphSlider = uislider(app.Tab);
            app.MorphSlider.ValueChangingFcn = createCallbackFcn(app, @MorphSliderValueChanging, true);
            app.MorphSliderLabel = uilabel(app.Tab);
            app.MorphSliderLabel.Text = '0';
            app.MarkerFileOpen = uibutton(app.Tab, 'push');
            app.MarkerFileOpen.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileOpenButtonPushed, true);
            app.MarkerFileOpen.Enable = 'off';
            app.MarkerFileOpen.Text = 'Marker File';
            app.MorphButterflyTrue = uicheckbox(app.Tab);
            app.MorphButterflyTrue.Text = 'Butterfly';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Binarize';
            app.Tab2.Units = 'pixels';
            app.LabelSlider2 = uilabel(app.Tab2);
            app.LabelSlider2.Text = 'Threshold';
            app.BinarizeSlider = uislider(app.Tab2);
            app.BinarizeSlider.Limits = [0 1];
            app.BinarizeSlider.ValueChangedFcn = createCallbackFcn(app, @BinarizeSliderValueChanged, true);
            app.BinarizeType = uibuttongroup(app.Tab2);
            app.BinarizeType.Title = 'Button Group';
            app.AdaptiveButton = uiradiobutton(app.BinarizeType);
            app.AdaptiveButton.Text = 'Adaptive';
            app.AdaptiveButton.Value = true;
            app.GlobalButton = uiradiobutton(app.BinarizeType);
            app.GlobalButton.Text = 'Global';
            app.ThreshSliderLabel = uilabel(app.Tab2);
            app.ThreshSliderLabel.Text = '0';
            app.ThreshGrayscaleTrue = uicheckbox(app.Tab2);
            app.ThreshGrayscaleTrue.Text = '1D Grayscale First';
            app.EdgesTab = uitab(app.TabGroup);
            app.EdgesTab.Title = 'Edges';
            app.EdgesTab.Units = 'pixels';
            app.TypeDropDownLabel = uilabel(app.EdgesTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.EdgeType = uidropdown(app.EdgesTab);
            app.EdgeType.Items = {'Sobel', 'Prewitt', 'LOG', '0-cross', 'Canny'};
            app.EdgeType.Value = 'Sobel';
            app.ThresholdSliderLabel = uilabel(app.EdgesTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.EdgeSlider = uislider(app.EdgesTab);
            app.EdgeSlider.Limits = [0 1];
            app.EdgeSlider.ValueChangedFcn = createCallbackFcn(app, @EdgeSliderValueChanged, true);
            app.SigmaHocrossLabel = uilabel(app.EdgesTab);
            app.SigmaHocrossLabel.Text = 'Sigma/H(o-cross)';
            app.EdgeSigma = uieditfield(app.EdgesTab, 'numeric');
            app.EdgeSigma.Value = 1;
            app.DirectionDropDownLabel = uilabel(app.EdgesTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.EdgeDir = uidropdown(app.EdgesTab);
            app.EdgeDir.Items = {'Horizontal', 'Vertical', 'Option 3', 'Option 4'};
            app.EdgeDir.Value = 'Horizontal';
            app.EdgeSliderLabel = uilabel(app.EdgesTab);
            app.EdgeSliderLabel.Text = '0';
            app.EdgeGrayscaleTrue = uicheckbox(app.EdgesTab);
            app.EdgeGrayscaleTrue.Text = '1D Grayscale First';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
        end
    end

    methods (Access = public)

        % Construct app
        function app = preprocessing()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end